classdef NoiseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        NoiseAxes                      matlab.ui.control.UIAxes
        StartEditFieldLabel            matlab.ui.control.Label
        StartEditField                 matlab.ui.control.EditField
        EndEditFieldLabel              matlab.ui.control.Label
        EndEditField                   matlab.ui.control.EditField
        TotalmeasurementdurationLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        ImportButton                   matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ImportfileButton               matlab.ui.control.RadioButton
        ImportfolderButton             matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        %Structs
        Patient;
        Company;
        %Fields
        filesToAnalyze;
        selectedFolder;
        currentPatientIndex;
        afterHoursTable;
        keyTable;
    end
    
    methods (Access = private)
        
        %Used to find and set work hours for each patient.
        %Uses the patient id to find the corresponding timestamp by indexing the afterHoursTable (Fyraftens tabel).
        function findWorkHours(app)
            dataTable = app.afterHoursTable(app.afterHoursTable.PatientID == str2num...
                (app.Patient(app.currentPatientIndex).ID),:);
            data = num2str(dataTable.Fyraften, '%.2f');
            endOfWork = datetime(data,'InputFormat','HH.mm',"Format","HH:mm:ss" );
            
            app.Patient(app.currentPatientIndex).Work.Start = app.Patient...
                (app.currentPatientIndex).FileData.Measurement(1,:).Time;
            app.Patient(app.currentPatientIndex).Work.Stop = endOfWork;
        end
    
        %Used to find which company the patient is working for by indexing the keytable (Nøgletabel).
        function findCompanyInformation(app)
            dataTable = app.keyTable(app.keyTable.PatientID == str2num...
                (app.Patient(app.currentPatientIndex).ID), :);
            app.Patient(app.currentPatientIndex).Company.ID = dataTable.VirksomhedsID;
            app.Patient(app.currentPatientIndex).Company.Name = dataTable.VirksomhedsNavn;
        end
        
        function updateTextBoxes(app)
            app.StartEditField.Value = datestr(app.Patient...
                (app.currentPatientIndex).FileData.Measurement(1,:).fullData);
            app.EndEditField.Value   = datestr(app.Patient...
                (app.currentPatientIndex).FileData.Measurement...
                (height(app.Patient(app.currentPatientIndex).FileData.Measurement),:).fullData);
        end
        
        %Calculates Leq based upon the user defined timeperiod.
        %Indexes the data based upon user inputs.
        function calculateUserDefinedLeq(app)
            app.Patient(app.currentPatientIndex).FileData.Measurement(1,:);
            measurementInterval = app.Patient(app.currentPatientIndex).FileData.Measurement(...
                app.Patient(app.currentPatientIndex).FileData.Measurement(:,:).fullData...
                >= datetime(app.StartEditField.Value) & ...
                app.Patient(app.currentPatientIndex).FileData.Measurement(:,:).fullData...
                <= datetime(app.EndEditField.Value),:);
            
            app.Patient(app.currentPatientIndex).UserDefined.Measurement = measurementInterval;
            
            app.Patient(app.currentPatientIndex).UserDefined.Leq = calculateLeq...
                (app, app.Patient(app.currentPatientIndex).UserDefined.Measurement);
        end
        
        %Calculates Leq for the entire period.
        function calculateTotalLeq(app)
            app.Patient(app.currentPatientIndex).FileData.Leq = calculateLeq...
                (app, app.Patient(app.currentPatientIndex).FileData.Measurement);
            
            app.Patient(app.currentPatientIndex).FileData.Start = app.Patient...
                (app.currentPatientIndex).FileData.Measurement(1,:).fullData;
            app.Patient(app.currentPatientIndex).FileData.Stop = app.Patient...
                (app.currentPatientIndex).FileData.Measurement...
                (height(app.Patient(app.currentPatientIndex).FileData.Measurement),:).fullData;
            
            app.Patient(app.currentPatientIndex).FileData.Duration = app.Patient...
                (app.currentPatientIndex).FileData.Stop - app.Patient...
                (app.currentPatientIndex).FileData.Start;
            [h, m, s] = hms(app.Patient(app.currentPatientIndex).FileData.Duration);
            app.TotalmeasurementdurationLabel.Text = sprintf...
                ('Total measurement duration: %.0f h, %.0f min, %.0f sec', h, m, s);
        end
        
        %Used by other methods to calculate leq.
        function leq = calculateLeq(~, dosimetriData)
            nominator = 0;
            validReadings = 0;
            for i = 1:height(dosimetriData)
                value = dosimetriData.Leq(i);
                if (isnan(value) == false)
                    nominator = nominator + 10^(value/10);
                    validReadings = validReadings + 1;
                end
            end
            leq = log10(nominator/validReadings)*10;
        end
        
        %Used by other methods to calculate leq for multiple measurements.
        function leq = calculateLeqBasedOnReadings(~, readings)
            nominator = 0;
            for i = 1:length(readings)
                nominator = nominator + 10^(readings(i)/10);
            end
            leq = log10(nominator/length(readings))*10;
        end
        
        %Used to plot data when one single file is loaded.
        function updateGraph(app)
            plot(app.Patient(app.currentPatientIndex).FileData.Measurement.fullData,app.Patient...
                (app.currentPatientIndex).FileData.Measurement.Leq, 'Parent',app.NoiseAxes);
            ylineTotalLeq = yline(app.NoiseAxes,app.Patient(app.currentPatientIndex).FileData.Leq,'-');
            legend(app.NoiseAxes, ylineTotalLeq,'Leq_{Total}');
        end
        
        %Used to update the UI if the user changes the timeinterval or if the user presses 'Calculate'.
        function updateGraphBasedOnUserInput(app)
            plot(app.Patient(app.currentPatientIndex).UserDefined.Measurement.fullData,app.Patient...
                (app.currentPatientIndex).UserDefined.Measurement.Leq, 'Parent',app.NoiseAxes);
            ylineuserDefinedleq = yline(app.NoiseAxes,app.Patient(app.currentPatientIndex).UserDefined.Leq,'-r');
            ylineTotalLeq = yline(app.NoiseAxes,app.Patient(app.currentPatientIndex).FileData.Leq,'-');
            legend(app.NoiseAxes, [ylineuserDefinedleq ylineTotalLeq],'Leq_{Userdefined}', 'Leq_{Total}');
        end
    
    %Method used to export one single file as a .csv file.
    function exportSingleFileDataAsCsv(app)
            DataToFile = {app.Patient(app.currentPatientIndex).ID, ...
                app.Patient(app.currentPatientIndex).Company.ID, ...
                app.Patient(app.currentPatientIndex).Company.Name, ...
                app.Patient(app.currentPatientIndex).FileData.Leq, ...
                app.Patient(app.currentPatientIndex).FileData.Start, ...
                app.Patient(app.currentPatientIndex).FileData.Stop, ...
                app.Patient(app.currentPatientIndex).FileData.Duration, ...
                app.Patient(app.currentPatientIndex).UserDefined.Leq, ...
                app.StartEditField.Value, ...
                app.EndEditField.Value};
            DataToTable = cell2table(DataToFile);
            DataToTable.Properties.VariableNames = {'PatientId', 'CompanyId', 'CompanyName', 'TotalLeq', ...
                'MeasurementStartTime','MeasurementStopTime','MeasureDuration', ...
                'UserConfigureredLeq', 'UserConfiguredStartTime','UserConfiguredStopTime'};
            currentTime = datestr(now,'dd-mm-yyyy_HH-MM-ss');
            fileName = sprintf('Company%.0f_Patient%s_%s.csv', app.Patient(app.currentPatientIndex).Company.ID, app.Patient(app.currentPatientIndex).ID, currentTime);
            writetable(DataToTable,fileName, 'Delimiter',';','WriteVariableNames',true);
            uiwait(msgbox('File exported successfully','Success','modal'));            
        end
    
        %Method used to export multiple files as a .csv files.
        function exportCompanyDataAsCsv(app)
            for i=1:numel(app.Company)
                DataToFile = {app.Company(i).ID,...
                    length(app.Company(i).Readings),...
                    app.Company(i).CombinedLeq};
                DataToTable = cell2table(DataToFile);
                DataToTable.Properties.VariableNames = {'CompanyId', 'NumEmployees','TotalLeq'};
                currentTime = datestr(now,'dd-mm-yyyy_HH-MM-ss');
                fileName = sprintf('Company%.0f_WorkingHoursLeq_%s.csv', app.Company(i).ID, currentTime);
                writetable(DataToTable, fileName, 'Delimiter', ';', 'WriteVariableNames', true);
            end
            uiwait(msgbox('Files exported successfully','Success','modal'));            
        end
    
        %Used to analyze all the files in one folder.
        function analyzeFiles(app)
         loadProgressbar = waitbar(.00,'Loading files from folder');

         companyIds = zeros(length(app.filesToAnalyze),1);
            for i = 1:length(app.filesToAnalyze)
                app.currentPatientIndex = i;
                % Using waitbar to illustrate loading progress.
                waitbar(i/length(app.filesToAnalyze),loadProgressbar,'Loading patient file...');

                fullfilePath = fullfile(app.selectedFolder, app.filesToAnalyze(i).name);
                [~, fileName, ~] = fileparts(fullfilePath);
                app.Patient(i).ID = fileName;
               
                %Imports one single file at a time.
                %Below is the code showing how to work with every single file one at a time.
                %The code is basically just calling the methods explained above.
                app.Patient(i).FileData.Measurement = importSingleFileFunction(fullfilePath);
                app.Patient(i).FileData.Leq = calculateLeq(app, app.Patient(i).FileData.Measurement);
                
                findWorkHours(app);
        
                app.Patient(i).Work.Measurement = app.Patient(i).FileData.Measurement(...
                    app.Patient(i).FileData.Measurement(:,:).Time >= app.Patient(i).Work.Start & ...
                    app.Patient(i).FileData.Measurement(:,:).Time <= app.Patient(app.currentPatientIndex).Work.Stop,:);
                
                %Calculating leq for workhours. 
                app.Patient(i).Work.Leq = calculateLeq(app, app.Patient(i).Work.Measurement);
                
                findCompanyInformation(app);
               
                app.Patient(i).Sparetime.Measurement = app.Patient(i).FileData.Measurement...
                    (app.Patient(i).FileData.Measurement(:,:).Datapoint> height(app.Patient(i).Work.Measurement),:);
               
                companyIds(i) = app.Patient(i).Company.ID;
            end
            %Gets all the unique company ids.
            uniqueCompanyIds = unique(companyIds);
            
            %Iterating through unique company ids and calculating their total leq.
            for i = 1:length(uniqueCompanyIds)
                text = sprintf('Performing calculations for firm %.0f',uniqueCompanyIds(i));
                waitbar(i/length(uniqueCompanyIds),loadProgressbar,text);
                app.Company(i).ID = uniqueCompanyIds(i);
                count = 1;
                for q = 1:numel(app.Patient)
                    if(app.Patient(q).Company.ID == app.Company(i).ID)
                        app.Company(i).Readings(count) = app.Patient(q).Work.Leq;
                        count = count + 1;
                    end
                end
                app.Company(i).CombinedLeq = calculateLeqBasedOnReadings(app,app.Company(i).Readings);
            end
            close(loadProgressbar);
        end
    end
    

    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
         app.keyTable = readtable('Data/Noeglefil.csv');
         app.afterHoursTable = readtable('Data\Fyraften.csv');
         
         app.ExportButton.Enable = false;
            if(app.ImportfileButton.Value == true)
                 app.CalculateButton.Enable = true;
                [FileName, Pathname] = uigetfile({'*.txt'}, 'Select .txt file');
                if FileName ~=0
                    app.currentPatientIndex = 1;
                    Fullpath = fullfile(Pathname, FileName);
                    [~, fileName, ~] = fileparts(Fullpath);
                    
                    loadProgressbar = waitbar(.25,'Importing patient data...');
                    
                    app.Patient(app.currentPatientIndex).FileData.Measurement = importSingleFileFunction(Fullpath);
                    app.Patient(app.currentPatientIndex).ID = fileName;
                    
                    waitbar(.70,loadProgressbar,'Performing calculations...');
                    
                    findCompanyInformation(app);
                    updateTextBoxes(app);
                    calculateTotalLeq(app);

                    waitbar(1,loadProgressbar,'Updating figure...');
                    
                    updateGraph(app);
                    close(loadProgressbar)
                end
            end
            if(app.ImportfolderButton.Value == true)
                app.CalculateButton.Enable = false;
                app.selectedFolder = uigetdir;
                app.filesToAnalyze = dir(fullfile(app.selectedFolder,'*.txt'));
                % Emptying patient struct to clear patient struct in case a single file was imported.
                app.Patient = [];
                analyzeFiles(app);
                exportCompanyDataAsCsv(app);
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calculateUserDefinedLeq(app);
            updateGraphBasedOnUserInput(app);
            app.ExportButton.Enable = true;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
                exportSingleFileDataAsCsv(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.NoiseAxes = uiaxes(app.Panel);
            title(app.NoiseAxes, 'Measurement')
            xlabel(app.NoiseAxes, 'Time(s)')
            ylabel(app.NoiseAxes, 'Sound Pressure Level[dB]')
            app.NoiseAxes.XTick = [];
            app.NoiseAxes.YTick = [5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.NoiseAxes.YTickLabel = {'5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'; '85'; '90'; '95'; '100'};
            app.StartEditFieldLabel = uilabel(app.Panel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Panel, 'text');
            app.EndEditFieldLabel = uilabel(app.Panel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.Panel, 'text');
            app.TotalmeasurementdurationLabel = uilabel(app.Panel);
            app.TotalmeasurementdurationLabel.Text = 'Total measurement duration:';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ImportButton = uibutton(app.Panel_2, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.Panel_2, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ImportfileButton = uiradiobutton(app.ButtonGroup);
            app.ImportfileButton.Text = 'Import file';
            app.ImportfileButton.Value = true;
            app.ImportfolderButton = uiradiobutton(app.ButtonGroup);
            app.ImportfolderButton.Text = 'Import folder';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NoiseApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end